classdef LegTorqueComputer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        PropertiesStdMetricLabel   matlab.ui.control.Label
        RobotWidthSlider           matlab.ui.control.Slider
        RobotWidthSliderLabel      matlab.ui.control.Label
        CarryingMassSlider         matlab.ui.control.Slider
        CarryingMassSliderLabel    matlab.ui.control.Label
        RobotMassSlider            matlab.ui.control.Slider
        RobotMassLabel             matlab.ui.control.Label
        CoxaLengthSlider           matlab.ui.control.Slider
        CoxaLengthSliderLabel      matlab.ui.control.Label
        FemurLengthSlider          matlab.ui.control.Slider
        FemurLengthSliderLabel     matlab.ui.control.Label
        TibiaLengthSlider          matlab.ui.control.Slider
        TibiaLengthLabel           matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        DisplayOptionsButtonGroup  matlab.ui.container.ButtonGroup
        TorquesButton              matlab.ui.control.ToggleButton
        NormalForcesButton         matlab.ui.control.ToggleButton
        T2Label                    matlab.ui.control.Label
        MaxTorqueShoulderLabel     matlab.ui.control.Label
        T1Label                    matlab.ui.control.Label
        MaxTorqueKneeLabel         matlab.ui.control.Label
        LegconfigurationPanel      matlab.ui.container.Panel
        UpdateButton               matlab.ui.control.Button
        HipMassSlider              matlab.ui.control.Slider
        HipMassSliderLabel         matlab.ui.control.Label
        ShoulderMassSlider         matlab.ui.control.Slider
        ShoulderMassSliderLabel    matlab.ui.control.Label
        KneeMassSlider             matlab.ui.control.Slider
        KneeMassSliderLabel        matlab.ui.control.Label
        LegMassSlider              matlab.ui.control.Slider
        LegMassSliderLabel         matlab.ui.control.Label
        N1Graph                    matlab.ui.control.UIAxes
        N2Graph                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CarryingMassSlider, CoxaLengthSlider, 
        % ...and 11 other components
        function PropertiesChanged(app, event)
            robotWidth = app.RobotWidthSlider.Value;
            hipOffset = app.CoxaLengthSlider.Value;
            
            % Body Specifications
            aL = hipOffset + robotWidth / 2;
            fL = app.FemurLengthSlider.Value;
            tL = app.TibiaLengthSlider.Value;
            
            % Weight on center of gravity
            rW = app.RobotMassSlider.Value * 9.81;
            cW = app.CarryingMassSlider.Value * 9.81;
            lW = app.LegMassSlider.Value * 9.81;
            W1 = app.KneeMassSlider.Value * 9.81;
            W2 = app.ShoulderMassSlider.Value * 9.81;
            W3 = app.HipMassSlider.Value * 9.81;
           
            % Computing W4 (Check diagram)
            W4 = cW + rW + 3*lW;
            
            % Computing the N2 Matrix
            
            [tetha1, tetha2] = meshgrid(-90:1:90);
            

            % Computing N2 from the torque balance about N1:
            leftSide = W1 .* tL .* cosd(tetha1) + W2 .* (tL .* cosd(tetha1) + fL.*cosd(tetha2)) + W3 .* (tL .* cosd(tetha1) + fL.*cosd(tetha2));
            middleSide = W4 .* (tL .* cosd(tetha1) + fL .* cosd(tetha2) + aL);
            rightSide = 2 .* W3 .* (tL .* cosd(tetha1) + fL .* cosd(tetha2) + 2 .* aL) + 2 .* W2 .* (tL .* cosd(tetha1) + fL .* cosd(tetha2) + 2 .* aL) + 2 .* W1 .* (tL .* cosd(tetha1) + 2 .* fL .* cosd(tetha2) + 2 .* aL);
           
            N2 = (leftSide + middleSide + rightSide) ./ (2 .* (2 .* tL .* cosd(tetha1) + 2 .* fL .* cosd(tetha2) + 2 .* aL));
            
            N1 = W4 + 3*lW - 2 .* N2;
            
            % Computing Knee Torque Requirement
            %T1 = W4 .* (fL .* cosd(tetha2) + aL) ;
            T1 = abs(N1 .* tL .* cosd(tetha1) + W2 .* fL .* cosd(tetha2) + W3 .* fL .* cosd(tetha2) + 2 .* W3 .* (fL .* cosd(tetha2) + 2 .* aL) + 2 .* W2 .* (fL .* cosd(tetha2) + 2 .* aL) + 2 .* W1 .* (2 .* fL .* cosd(tetha2) + 2 .* aL) - 2 .* N2 .* (2 .* fL .* cosd(tetha2) + 2 .* aL + tL .* cosd(tetha1)) + W4 .* (fL .* cosd(tetha2) + aL));
            
            % Computing Shoulder Torque Requirement 
            T2 = abs(N1 .* (tL .* cosd(tetha1) + fL .* cosd(tetha2)) - W1 .* (fL .* cosd(tetha2)) + W4 .* aL + 2 .* W2 .* aL + 2 .* W3 .* aL + 2 .* W1 .* (2 .* aL + fL .* cosd(tetha2)) - 2 .* N2 .* (2 .* aL + fL .* cosd(tetha2) + tL .* cosd(tetha1)));
            
            switch app.DisplayOptionsButtonGroup.SelectedObject.Text
                case 'Normal Forces'
                    surf(app.N1Graph, tetha1, tetha2, N1);
                    title(app.N1Graph, "Normal Force 1 (N)");
                    app.N1Graph.ZLabel.String = "Force (N)";
                    surf(app.N2Graph, tetha1, tetha2, N2);
                    title(app.N2Graph, "Normal Force 2 (N)");
                    app.N2Graph.ZLabel.String = "Force (N)";
                case 'Torques'
                    surf(app.N1Graph, tetha1, tetha2, T1);
                    title(app.N1Graph, "Knee Torque (N-m)");
                    app.N1Graph.ZLabel.String = "Torque (N-m)";
                    surf(app.N2Graph, tetha1, tetha2, T2);
                    title(app.N2Graph, "Shoulder Torque (N-m)");
                    app.N2Graph.ZLabel.String = "Torque (N-m)";
                    
            end

            app.T1Label.Text = num2str(max(T1, [], 'all'));
            app.T2Label.Text = num2str(max(T2, [], 'all'));

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {630, 630};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {211, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TibiaLengthLabel = uilabel(app.LeftPanel);
            app.TibiaLengthLabel.Text = 'Tibia Length:';
            app.TibiaLengthSlider = uislider(app.LeftPanel);
            app.TibiaLengthSlider.Limits = [0 0.5];
            app.TibiaLengthSlider.ValueChangedFcn = createCallbackFcn(app, @PropertiesChanged, true);
            app.TibiaLengthSlider.Value = 0.15;
            app.FemurLengthSliderLabel = uilabel(app.LeftPanel);
            app.FemurLengthSliderLabel.Text = 'Femur Length:';
            app.FemurLengthSlider = uislider(app.LeftPanel);
            app.FemurLengthSlider.Limits = [0 0.5];
            app.FemurLengthSlider.ValueChangedFcn = createCallbackFcn(app, @PropertiesChanged, true);
            app.FemurLengthSlider.Value = 0.1;
            app.CoxaLengthSliderLabel = uilabel(app.LeftPanel);
            app.CoxaLengthSliderLabel.Text = 'Coxa Length:';
            app.CoxaLengthSlider = uislider(app.LeftPanel);
            app.CoxaLengthSlider.Limits = [0 0.5];
            app.CoxaLengthSlider.ValueChangedFcn = createCallbackFcn(app, @PropertiesChanged, true);
            app.CoxaLengthSlider.Value = 0.1;
            app.RobotMassLabel = uilabel(app.LeftPanel);
            app.RobotMassLabel.Text = 'Robot Mass:';
            app.RobotMassSlider = uislider(app.LeftPanel);
            app.RobotMassSlider.Limits = [0 10];
            app.RobotMassSlider.ValueChangedFcn = createCallbackFcn(app, @PropertiesChanged, true);
            app.RobotMassSlider.Value = 5;
            app.CarryingMassSliderLabel = uilabel(app.LeftPanel);
            app.CarryingMassSliderLabel.Text = 'Carrying Mass:';
            app.CarryingMassSlider = uislider(app.LeftPanel);
            app.CarryingMassSlider.Limits = [0 10];
            app.CarryingMassSlider.ValueChangedFcn = createCallbackFcn(app, @PropertiesChanged, true);
            app.CarryingMassSlider.Value = 5;
            app.RobotWidthSliderLabel = uilabel(app.LeftPanel);
            app.RobotWidthSliderLabel.Text = 'Robot Width:';
            app.RobotWidthSlider = uislider(app.LeftPanel);
            app.RobotWidthSlider.Limits = [0 0.5];
            app.RobotWidthSlider.ValueChangedFcn = createCallbackFcn(app, @PropertiesChanged, true);
            app.RobotWidthSlider.Value = 0.2;
            app.PropertiesStdMetricLabel = uilabel(app.LeftPanel);
            app.PropertiesStdMetricLabel.Text = 'Properties (Std Metric):';
            app.RightPanel = uipanel(app.GridLayout);
            app.N2Graph = uiaxes(app.RightPanel);
            title(app.N2Graph, 'N2 Values (N)')
            xlabel(app.N2Graph, 'Knee Angle')
            ylabel(app.N2Graph, 'Shoulder Angle')
            zlabel(app.N2Graph, 'N2 Value')
            colormap(app.N2Graph, 'turbo')
            app.N1Graph = uiaxes(app.RightPanel);
            title(app.N1Graph, 'N1 Values (N)')
            xlabel(app.N1Graph, 'Knee Angle')
            ylabel(app.N1Graph, 'Shoulder Angle')
            zlabel(app.N1Graph, 'N1 Value')
            colormap(app.N1Graph, 'turbo')
            app.LegconfigurationPanel = uipanel(app.RightPanel);
            app.LegconfigurationPanel.Title = 'Leg configuration';
            app.LegMassSliderLabel = uilabel(app.LegconfigurationPanel);
            app.LegMassSliderLabel.Text = 'Leg Mass:';
            app.LegMassSlider = uislider(app.LegconfigurationPanel);
            app.LegMassSlider.Limits = [0 1];
            app.LegMassSlider.ValueChangedFcn = createCallbackFcn(app, @PropertiesChanged, true);
            app.LegMassSlider.Value = 0.3;
            app.KneeMassSliderLabel = uilabel(app.LegconfigurationPanel);
            app.KneeMassSliderLabel.Text = 'Knee Mass:';
            app.KneeMassSlider = uislider(app.LegconfigurationPanel);
            app.KneeMassSlider.Limits = [0 0.3];
            app.KneeMassSlider.ValueChangedFcn = createCallbackFcn(app, @PropertiesChanged, true);
            app.KneeMassSlider.Value = 0.06;
            app.ShoulderMassSliderLabel = uilabel(app.LegconfigurationPanel);
            app.ShoulderMassSliderLabel.Text = 'Shoulder Mass:';
            app.ShoulderMassSlider = uislider(app.LegconfigurationPanel);
            app.ShoulderMassSlider.Limits = [0 0.3];
            app.ShoulderMassSlider.ValueChangedFcn = createCallbackFcn(app, @PropertiesChanged, true);
            app.ShoulderMassSlider.Value = 0.06;
            app.HipMassSliderLabel = uilabel(app.LegconfigurationPanel);
            app.HipMassSliderLabel.Text = 'Hip Mass:';
            app.HipMassSlider = uislider(app.LegconfigurationPanel);
            app.HipMassSlider.Limits = [0 0.3];
            app.HipMassSlider.ValueChangedFcn = createCallbackFcn(app, @PropertiesChanged, true);
            app.HipMassSlider.Value = 0.06;
            app.UpdateButton = uibutton(app.LegconfigurationPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @PropertiesChanged, true);
            app.UpdateButton.Text = 'Update';
            app.MaxTorqueKneeLabel = uilabel(app.RightPanel);
            app.MaxTorqueKneeLabel.Text = 'Max Torque Knee:';
            app.T1Label = uilabel(app.RightPanel);
            app.T1Label.Text = 'T1';
            app.MaxTorqueShoulderLabel = uilabel(app.RightPanel);
            app.MaxTorqueShoulderLabel.Text = 'Max Torque Shoulder:';
            app.T2Label = uilabel(app.RightPanel);
            app.T2Label.Text = 'T2';
            app.DisplayOptionsButtonGroup = uibuttongroup(app.RightPanel);
            app.DisplayOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PropertiesChanged, true);
            app.DisplayOptionsButtonGroup.Title = 'Display Options';
            app.DisplayOptionsButtonGroup.ButtonDownFcn = createCallbackFcn(app, @PropertiesChanged, true);
            app.NormalForcesButton = uitogglebutton(app.DisplayOptionsButtonGroup);
            app.NormalForcesButton.Text = 'Normal Forces';
            app.NormalForcesButton.Value = true;
            app.TorquesButton = uitogglebutton(app.DisplayOptionsButtonGroup);
            app.TorquesButton.Text = 'Torques';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LegTorqueComputer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end